classdef upload_to_harvard_dataverse < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        LastFileUploadedEditField       matlab.ui.control.EditField
        LastFileUploadedEditFieldLabel  matlab.ui.control.Label
        ORLabel                         matlab.ui.control.Label
        FileToStartWithAsNumberEditField  matlab.ui.control.NumericEditField
        FileToStartWithAsNumberEditFieldLabel  matlab.ui.control.Label
        DoyouwanttoskipfilesButtonGroup  matlab.ui.container.ButtonGroup
        YesButton                       matlab.ui.control.RadioButton
        NoButton_2                      matlab.ui.control.RadioButton
        StatusTextArea                  matlab.ui.control.TextArea
        StatusTextAreaLabel             matlab.ui.control.Label
        DownloadLabel                   matlab.ui.control.Label
        UploadLabel                     matlab.ui.control.Label
        Label2_2                        matlab.ui.control.Label
        Label2                          matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        IwantToLabel                    matlab.ui.control.Label
        DownloadDataSetButton           matlab.ui.control.Button
        FromRealDataVerseCheckBox       matlab.ui.control.CheckBox
        YourDoiEditField                matlab.ui.control.EditField
        YourDoiEditFieldLabel           matlab.ui.control.Label
        YourAPIkeyEditField             matlab.ui.control.EditField
        YourAPIkeyEditFieldLabel        matlab.ui.control.Label
        UploadButton                    matlab.ui.control.Button
        YourFileEditField               matlab.ui.control.EditField
        YourFileEditFieldLabel          matlab.ui.control.Label
        PickAFileButton                 matlab.ui.control.Button
    end


    properties (Access = private)
        dir_to_pick = "" % the directory with all the data you want to upload
        demo_or_real = "https://demo.dataverse.org";
        replace_forbidden_character = false;
        could_not_be_uploaded_counter = 0;
        list_of_files_that_cannot_be_uploaded = [];
        temp_files_created = 0;
        location_of_tmp_files_to_be_deleted = "";
        file_to_start_with = 1;
    end

    methods (Access = private)

        function fileList = listAllFiles(app,folder)
            % Initialize the file list
            fileList = {};

            % Get the data for the current folder
            files = dir(folder);

            % Loop through each item in the folder
            for i = 1:length(files)
                % Skip the '.' and '..' folders
                if strcmp(files(i).name, '.') || strcmp(files(i).name, '..')
                    continue;
                end

                % Get the full path of the file/folder
                fullPath = fullfile(folder, files(i).name);

                % If it's a directory, recursively call the function
                if files(i).isdir
                    fileList = [fileList; listAllFiles(app,fullPath)];
                else
                    % Otherwise, add the file to the list
                    fileList = [fileList; {fullPath}];
                end
            end
        end
        function dir_list = list_all_dirs(app,folder)
            % Initialize the file list
            dir_list = {};

            % Get the data for the current folder
            files = dir(folder);

            % Loop through each item in the folder
            for i = 1:length(files)
                % Skip the '.' and '..' folders
                if strcmp(files(i).name, '.') || strcmp(files(i).name, '..')
                    continue;
                end

                % Get the full path of the file/folder
                fullPath = fullfile(folder, files(i).name);

                % If it's a directory, recursively call the function
                if files(i).isdir
                    dir_list = [dir_list;{fullPath}; list_all_dirs(app,fullPath)];
                end
            end
        end


        function detect_chunk_files(app,data_folder)
            list_of_all_files = list_all_dirs(app,data_folder);
            for i=1:length(list_of_all_files)
                current_file = list_of_all_files{i};
                if contains(current_file,"_Chunked")
                    home_dir = cd(current_file);
                    list_of_all_chunked_files = ls(current_file+"\*.dat");
                    original_file_location = current_file;
                    original_filename = split(current_file,"\");
                    original_filename = original_filename{end};
                    original_file_location = strrep(original_file_location,original_filename,"");
                    original_filename = strrep(original_filename,"_Chunked","");
                    cd("..");
                    original_file_id = fopen(original_filename,'a+');
                    for j=1:length(strtrim(string(list_of_all_chunked_files)))
                        chunkFileName = sprintf('%s\\chunk_%d.dat',current_file, j);
                        chunkFileID = fopen(chunkFileName, 'r');
                        data = fread(chunkFileID, 'uint8');
                        fwrite(original_file_id,data,'uint8')
                        fclose(chunkFileID);
                    end
                    fclose('all');
                    %fclose(original_file_id);
                    rmdir(current_file,'s')
                    cd(home_dir);
                end

            end
        end
        function [new_temp_file_location,parent_dir_to_delete] = copy_file_to_some_local_area_and_return_new_file_path(app,original_file_location,forbidden_chars)
            
            new_temp_file_location = original_file_location;
            for i=1:length(forbidden_chars)
                new_temp_file_location = strrep(new_temp_file_location,forbidden_chars(i),"_");
            end
            % new_temp_file_location = "temp_"+new_temp_file_location;
            split_new_loc = split(new_temp_file_location,"\");
            split_new_loc{2} = ['TEMP_',+split_new_loc{2}];
            for i=2:length(split_new_loc)-1
                mkdir(fullfile(split_new_loc{1:i}))
                if i==2
                    parent_dir_to_delete = fullfile(split_new_loc{1:i});
                end
            end
            new_temp_file_location = join(split_new_loc,"\");
            copyfile(original_file_location,join(split_new_loc,"\"))
        end
        function split_file_into_smaller_chunks(app,file_that_is_too_big,directory_label,size_of_files_in_bytes)
            % fileID = fopen(file_that_is_too_big, 'rb');
            % data = fread(fileID, 'uint8');
            % fclose(fileID);
            chunk_size = 1000000000;
            number_of_chunks = ceil(size_of_files_in_bytes / chunk_size);
            dir_to_save_tmp_files_to = pwd+"\"+file_that_is_too_big+ "_Chunked\";
            api_key = app.YourAPIkeyEditField.Value;
            doi = app.YourDoiEditField.Value;
            only_the_file_name = split(file_that_is_too_big,"\");
            only_the_file_name = only_the_file_name(end);
            fileID = fopen(file_that_is_too_big, 'r');
            for i=1:number_of_chunks
                data = fread(fileID,chunk_size, 'uint8');
                chunkFileName = sprintf('chunk_%d.dat', i);
                chunkFileID = fopen(chunkFileName, 'w');
                fwrite(chunkFileID, data, 'uint8');
                fclose(chunkFileID);

                command_to_run = ['curl -H "X-Dataverse-key:',char(api_key),'" -X POST -F "file=@\"',char(chunkFileName),'\"" -F "jsonData={\"description\":\"My description.\",\"directoryLabel\":\"data/',char(string(directory_label)+"/"+only_the_file_name+"_Chunked"),'\",\"categories\":[\"Data\"],\"restrict\":\"false\",\"tabIngest\":\"false\"}" "',char(app.demo_or_real),'/api/datasets/:persistentId/add?persistentId=doi:',doi,'"'];
                disp(command_to_run);
                % command_to_run = "curl -H ""X-Dataverse-key:$env:API_TOKEN"" -X POST -F ""file=@$env:FILENAME"" -F 'jsonData={""description"":"""",""directoryLabel"":""data/"+string(directory_label)+"/"+only_the_file_name+"_Chunked"+""",""categories"":[""Data""], ""restrict"":""false"", ""tabIngest"":""false""}'" +"$env:SERVER_URL/api/datasets/:persistentId/add?persistentId=env:$PERSISTENT_ID";
                % command_to_run = "curl -v -H ""X-Dataverse-key:$API_TOKEN"" POST -F ""file=@%FILENAME%"" -F 'jsonData={""description"":"""",""directoryLabel"":""data/"+string(directory_label)+"/"+only_the_file_name+"_Chunked"+""",""categories"":[""Data""], ""restrict"":""false"", ""tabIngest"":""false""}'" +"$SERVER_URL/api/datasets/:persistentId/add?persistentId=doi:$PERSISTENT_ID";

                % command_to_run = sprintf(['pwsh -Command "curl -v -H ""X-Dataverse-key:%s"" -X POST '...
                %     '-F ""file=@%s"" '...
                %     '-F ''jsonData={\\"description\\":\\"\\",\\"directoryLabel\\":\\"data/%s/%s_Chunked\\",\\"categories\\":[\\"Data\\"], \\"restrict\\":\\"false\\", \\"tabIngest\\":\\"false\\"}'' '...
                %     '"%s/api/datasets/:persistentId/add?persistentId=doi:%s""'], ...
                %     api_key, ...
                %     fullfile(pwd,chunkFileName), ...
                %     directory_label, ...
                %     only_the_file_name, ...
                %     app.demo_or_real, ...
                %     doi);
                % disp(command_to_run);
                [output, something] = system(command_to_run,"FILENAME",fullfile(pwd,chunkFileName),"SERVER_URL",app.demo_or_real,"API_TOKEN",string(api_key),"PERSISTENT_ID",string(doi));


                 % = system(command_to_run);

                if contains(something,"ERROR",IgnoreCase=true) || ~contains(something,'"OK"')
                    app.list_of_files_that_cannot_be_uploaded = [app.list_of_files_that_cannot_be_uploaded;current_file];
                end
                disp(something);
                pause(0.5);

                delete(chunkFileName);
            end
            fclose(fileID);


        end
        function [status_filename] = upload_files(app,filesToBeUploaded)
            clc;
            formatted_date_time = string(datetime('now'));
            formatted_date_time = strrep(formatted_date_time,"-","_");
            formatted_date_time = strrep(formatted_date_time,":","_");
            status_filename = "Status of your upload started at "+formatted_date_time+".txt";

            selectedButton = app.DoyouwanttoskipfilesButtonGroup.SelectedObject;
            selectedButton_Test = string(selectedButton.Text);

          
            if app.LastFileUploadedEditField.Value ~="" &&  selectedButton_Test=="Yes"
                for i=1:length(filesToBeUploaded)
                    if string(filesToBeUploaded{i}) ==string(app.LastFileUploadedEditField.Value)
                        beginning_index = i+1;
                        break;
                    end
                end
            elseif  selectedButton_Test=="Yes" && app.FileToStartWithAsNumberEditField.Value >1
                beginning_index = app.FileToStartWithAsNumberEditField.Value;
            else
                beginning_index=1;
            end
            for i=beginning_index:length(filesToBeUploaded)
                current_file = filesToBeUploaded{i};
                unusual_chars = regexp(current_file,'[^\w\\//.:]','match');

                disp("Your file forbidden characters")
                disp(unusual_chars)
                disp("These will be removed during upload. Your original file will not be touched.")

                if ~isempty(unusual_chars)
                    [current_file,parent_dir_to_delete] = copy_file_to_some_local_area_and_return_new_file_path(app,current_file,unusual_chars);
                    
                end
                data_of_file = dir(current_file);
   
                
                size_of_file_in_bytes = data_of_file.bytes;
                size_of_file_in_gigabytes = size_of_file_in_bytes / 1000000000;
                all_data = split(current_file,"\");
                directoryLabel = join(all_data(1:end-1),"\");
                % directoryLabel = directoryLabel{1};
                api_key = app.YourAPIkeyEditField.Value;
                doi = app.YourDoiEditField.Value;
                parent_dir = app.dir_to_pick;
                if ~isempty(unusual_chars)
                    for p=1:length(unusual_chars)
                        parent_dir = strrep(parent_dir,unusual_chars(p),"_");
                    end
                end
                parent_dir = split(parent_dir,"\");
                parent_dir = parent_dir{end};

                directoryLabel = char(directoryLabel);

                target_string = parent_dir;
                start_index = strfind(directoryLabel,target_string);
                directoryLabel = directoryLabel(start_index:end);


                directoryLabel = strrep(directoryLabel,"\","/");
                if size_of_file_in_gigabytes > 2
                    disp("ALERT !")
                    disp(current_file);
                    disp("Is Too Big for normal upload, will break it into chunks for better formatting")
                    split_file_into_smaller_chunks(app,current_file,directoryLabel,size_of_file_in_bytes)
                else
                    command_to_run = ['curl -H "X-Dataverse-key:',char(api_key),'" -X POST -F "file=@\"',char(current_file),'\"" -F "jsonData={\"description\":\"My description.\",\"directoryLabel\":\"data/',char(directoryLabel),'\",\"categories\":[\"Data\"],\"restrict\":\"false\",\"tabIngest\":\"false\"}" "',char(app.demo_or_real),'/api/datasets/:persistentId/add?persistentId=doi:',char(doi),'"'];
                    % disp(command_to_run);
                    % command_to_run = "curl -H ""X-Dataverse-key:$API_TOKEN"" -X POST -F ""file=@$FILENAME"" -F 'jsonData={""description"":"""",""directoryLabel"":""data/"+string(directoryLabel)+""",""categories"":[""Data""], ""restrict"":""false"", ""tabIngest"":""false""}'" +" $SERVER_URL/api/datasets/:persistentId/add?persistentId=doi:$PERSISTENT_ID";

                    % command_to_run = sprintf(['pwsh -Command "curl -v -H ""X-Dataverse-key:%s"" -X POST '...
                    %     '-F ""file=@%s"" '...
                    %     '-F ''jsonData={\\"description\\":\\"\\",\\"directoryLabel\\":\\"data/%s\\",\\"categories\\":[\\"Data\\"], \\"restrict\\":\\"false\\", \\"tabIngest\\":\\"false\\"}'' '...
                    %     '"%s/api/datasets/:persistentId/add?persistentId=doi:%s""'], ...
                    %     api_key, ...
                    %     current_file, ...
                    %     directoryLabel, ...
                    %     app.demo_or_real, ...
                    %     doi);
                    % disp(command_to_run);
                    % [output, something] = system(command_to_run,"FILENAME",current_file,"SERVER_URL",app.demo_or_real,"API_TOKEN",string(api_key),"PERSISTENT_ID",string(doi));
                    [output, something] = system(command_to_run);

                    if contains(something,"ERROR",IgnoreCase=true) || ~contains(something,'"OK"')
                        app.list_of_files_that_cannot_be_uploaded = [app.list_of_files_that_cannot_be_uploaded;current_file];
                    end
                    disp(something);
                    pause(0.5)
                end
                status_message = "Finished Uploading "+string(i) +"/"+string(length(filesToBeUploaded)+ ": "+string(current_file));
                
                % Define the file name
                
                
                % Open the file in append mode
                fileID = fopen(status_filename, 'a');
                % Check if the file was successfully opened
                if fileID == -1 %if it wasn't opened then create it 
                    fileID = fopen(status_filename, 'w'); %open it without writing to it
                    fclose(fileID); %close it now that its created
                    fileID = fopen(status_filename, 'a'); %open it again in preparation for writing
                end
                if fileID ~=-1
                    % Write some data to the file
                    fprintf(fileID, [char("\n Finished Uploading "+string(i) +"/"+string(length(filesToBeUploaded))),'%s\n']);
                    % Close the file
                    fclose(fileID);
                end
                app.StatusTextArea.Value = status_message;
                if ~isempty(unusual_chars)
                    app.temp_files_created = 1;
                    app.location_of_tmp_files_to_be_deleted = parent_dir_to_delete;
                end

            end

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: PickAFileButton, YourFileEditField
        function PickAFileToUploadButtonPushed(app, event)
            location = uigetdir;
            app.dir_to_pick = location;
            app.YourFileEditField.Value = location;
        end

        % Button pushed function: UploadButton
        function UploadButtonPushed(app, event)
            filesToBeUploaded = listAllFiles(app,app.dir_to_pick);
            [status_filename] =upload_files(app,filesToBeUploaded);
            if ~isempty(app.list_of_files_that_cannot_be_uploaded)
                % Open the file in append mode
                fileID = fopen(status_filename, 'a');
                final_status_message = "The Following files threw errors during upload process please check to make sure they were uploaded" +strjoin(app.list_of_files_that_cannot_be_uploaded.');
                if fileID ~=-1
                    % Write some data to the file
                    fprintf(fileID, final_status_message+'\n');
                    % Close the file
                    fclose(fileID);
                end
                app.StatusTextArea.Value = final_status_message;
            end
            if app.temp_files_created
                questdlg(char(strcat('temp files were created at',app.location_of_tmp_files_to_be_deleted,', remember to delete them')), ...
                    	'Alert for temp files', ...
                    	'ok');
            end
        end

        % Value changed function: FromRealDataVerseCheckBox
        function FromRealDataVerseCheckBoxValueChanged(app, event)
            value = app.FromRealDataVerseCheckBox.Value;
            if ~value
                app.demo_or_real = "https://demo.dataverse.org";
            else
                app.demo_or_real ="https://dataverse.harvard.edu";
            end
        end

        % Button pushed function: DownloadDataSetButton
        function DownloadDataSetButtonPushed(app, event)
            api_key = app.YourAPIkeyEditField.Value;
            doi = app.YourDoiEditField.Value;
            command_to_run = ['curl -L -O -J -H "X-Dataverse-key:',char(api_key),'" "',char(app.demo_or_real),'/api/access/dataset/:persistentId/?persistentId=doi:',doi,'"'];

            home_dir = cd(app.dir_to_pick);
            disp("Downloading dataset to "+app.dir_to_pick);
            disp("This Could Take a Moment ...")
            [output, something] = system(command_to_run,"-echo");
            disp("Finished Download, now unzipping")
            command_to_run = ['powershell -Command "Expand-Archive -Path \"',char(app.dir_to_pick),'\dataverse_files.zip\" -DestinationPath \"',char(app.dir_to_pick),'\""'];
            system(command_to_run)
            delete(app.dir_to_pick+"\dataverse_files.zip");
            disp("Finished Unzipping, now restoring chunked files")
            detect_chunk_files(app,app.dir_to_pick)
            disp("Finished Restoring, your files are ready to view")
            cd(home_dir);
        end

        % Button down function: DoyouwanttoskipfilesButtonGroup
        function DoyouwanttoskipfilesButtonGroupButtonDown(app, event)
            
        end

        % Selection changed function: DoyouwanttoskipfilesButtonGroup
        function DoyouwanttoskipfilesButtonGroupSelectionChanged(app, event)
            selectedButton = app.DoyouwanttoskipfilesButtonGroup.SelectedObject;
            selectedButton_Test = string(selectedButton.Text);
            % disp(selectedButton);
            if selectedButton_Test=="Yes"
                app.FileToStartWithAsNumberEditField.Editable = true;  
                app.LastFileUploadedEditField.Editable = true;
            else
                app.FileToStartWithAsNumberEditField.Value = 1;
                app.FileToStartWithAsNumberEditField.Editable = false;
                app.LastFileUploadedEditField.Value = "";
                app.LastFileUploadedEditField.Editable = false;
            end

        end

        % Value changed function: FileToStartWithAsNumberEditField
        function FileToStartWithAsNumberEditFieldValueChanged(app, event)
            value = app.FileToStartWithAsNumberEditField.Value;
            app.file_to_start_with = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PickAFileButton = uibutton(app.UIFigure, 'push');
            app.PickAFileButton.ButtonPushedFcn = createCallbackFcn(app, @PickAFileToUploadButtonPushed, true);
            app.PickAFileButton.Text = 'Pick A File';
            app.YourFileEditFieldLabel = uilabel(app.UIFigure);
            app.YourFileEditFieldLabel.Text = 'Your File';
            app.YourFileEditField = uieditfield(app.UIFigure, 'text');
            app.YourFileEditField.ValueChangedFcn = createCallbackFcn(app, @PickAFileToUploadButtonPushed, true);
            app.UploadButton = uibutton(app.UIFigure, 'push');
            app.UploadButton.ButtonPushedFcn = createCallbackFcn(app, @UploadButtonPushed, true);
            app.UploadButton.Text = 'Upload';
            app.YourAPIkeyEditFieldLabel = uilabel(app.UIFigure);
            app.YourAPIkeyEditFieldLabel.Text = 'Your API key';
            app.YourAPIkeyEditField = uieditfield(app.UIFigure, 'text');
            app.YourDoiEditFieldLabel = uilabel(app.UIFigure);
            app.YourDoiEditFieldLabel.Text = 'Your Doi:';
            app.YourDoiEditField = uieditfield(app.UIFigure, 'text');
            app.FromRealDataVerseCheckBox = uicheckbox(app.UIFigure);
            app.FromRealDataVerseCheckBox.ValueChangedFcn = createCallbackFcn(app, @FromRealDataVerseCheckBoxValueChanged, true);
            app.FromRealDataVerseCheckBox.Text = 'From Real Data Verse';
            app.DownloadDataSetButton = uibutton(app.UIFigure, 'push');
            app.DownloadDataSetButton.ButtonPushedFcn = createCallbackFcn(app, @DownloadDataSetButtonPushed, true);
            app.DownloadDataSetButton.Text = 'Download Data Set';
            app.IwantToLabel = uilabel(app.UIFigure);
            app.IwantToLabel.Text = 'I want To';
            app.Label = uilabel(app.UIFigure);
            app.Label2 = uilabel(app.UIFigure);
            app.Label2.Text = 'Your File Will Be Uploaded to Harvard Dataverse (demo/real depending on  whether or not you checked the box)';
            app.Label2_2 = uilabel(app.UIFigure);
            app.Label2_2.Text = 'Data From Harvard Dataverse will be downloaded and saved to your file';
            app.UploadLabel = uilabel(app.UIFigure);
            app.UploadLabel.Text = 'Upload';
            app.DownloadLabel = uilabel(app.UIFigure);
            app.DownloadLabel.Text = 'Download';
            app.StatusTextAreaLabel = uilabel(app.UIFigure);
            app.StatusTextAreaLabel.Text = 'Status';
            app.StatusTextArea = uitextarea(app.UIFigure);
            app.DoyouwanttoskipfilesButtonGroup = uibuttongroup(app.UIFigure);
            app.DoyouwanttoskipfilesButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DoyouwanttoskipfilesButtonGroupSelectionChanged, true);
            app.DoyouwanttoskipfilesButtonGroup.Title = 'Do you want to skip files?';
            app.DoyouwanttoskipfilesButtonGroup.ButtonDownFcn = createCallbackFcn(app, @DoyouwanttoskipfilesButtonGroupButtonDown, true);
            app.NoButton_2 = uiradiobutton(app.DoyouwanttoskipfilesButtonGroup);
            app.NoButton_2.Text = 'No';
            app.NoButton_2.Value = true;
            app.YesButton = uiradiobutton(app.DoyouwanttoskipfilesButtonGroup);
            app.YesButton.Text = 'Yes';
            app.FileToStartWithAsNumberEditFieldLabel = uilabel(app.UIFigure);
            app.FileToStartWithAsNumberEditFieldLabel.Text = 'File To Start With As Number';
            app.FileToStartWithAsNumberEditField = uieditfield(app.UIFigure, 'numeric');
            app.FileToStartWithAsNumberEditField.ValueChangedFcn = createCallbackFcn(app, @FileToStartWithAsNumberEditFieldValueChanged, true);
            app.FileToStartWithAsNumberEditField.Editable = 'off';
            app.FileToStartWithAsNumberEditField.Value = 1;
            app.ORLabel = uilabel(app.UIFigure);
            app.ORLabel.Text = 'OR ';
            app.LastFileUploadedEditFieldLabel = uilabel(app.UIFigure);
            app.LastFileUploadedEditFieldLabel.Text = 'Last File Uploaded';
            app.LastFileUploadedEditField = uieditfield(app.UIFigure, 'text');
            app.LastFileUploadedEditField.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = upload_to_harvard_dataverse

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end